classdef HyphaeSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        StartingImgUIAxes     matlab.ui.control.UIAxes
        HyphaeButton          matlab.ui.control.Button
        NotHyphaeButton       matlab.ui.control.Button
        FolderEditFieldLabel  matlab.ui.control.Label
        FolderEditField       matlab.ui.control.EditField
        BrowseButton          matlab.ui.control.Button
        GoBack1Button         matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        fibrousStruct         matlab.ui.control.UIAxes
        HoughTransform        matlab.ui.control.UIAxes
        UIAxes2_2             matlab.ui.control.UIAxes
        fig3                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fileList % Description
        folder % Description
        fileNum % Description
        lineNum
        hyphaeCnt
        featureMatrix
        targetMatrix
        
        %preDefined
        thickness = [7]; % Fiber thickness in pixels
        rhoRes = 3; % rho resolution for Hough transform, [pixels]
        thetaRes = 1; % angle resolution for Hough transform, [degrees]
        numPeaksLimit = 30; % The maximum number of peaks returned by houghpeaks()
        peakThreshold = 100; %100; % Threshold of Hough transform peak value
        fillGapLen = 20; % Maximum gap size defining line segments, [pixels]
        minLineLen = 200; %Minimum line segment length returned by houghlines(), [pixels]
        peakOverAvgThres = 3;
        adjacentLineRatioThres = 0.15;
        lineLengthThres = 200; % [pixels]
        d = [-8,-4,4,8]; %8; % Adjacent line disance away from line, [pixel length]
        
      
        B % Description
        BW % Description
        H % Description
        T % Description
        R % Description
        x % Description 
        y % Description
        xyForPlot % Description
        numLines % Description
        isHyphae % Description
        hp % Description
    end
    
    methods (Access = private)
        
        function results = processImage(app)
            leafImage = imread(pathFileName);
            leafImage = leafImage(:,:,3); % Blue channel
            [temphyphaeCnt,tempfMatrix,temptMatrix] = findHyphaeApp(app,leafImage);
            app.hyphaeCnt= [app.hyphaeCnt;temphyphaeCnt];
            app.featureMatrix= [app.featureMatrix;tempfMatrix];
            app.targetMatrix= [app.targetMatrix;temptMatrix];
            
        end
        
        function results = findHyphaeApp(app,leafImage)
            
            theta = -90:app.thetaRes:90-app.thetaRes; % Degrees
            B = fibermetric(leafImage, app.thickness, 'ObjectPolarity','bright'); %, 'StructureSensitivity', 3);
            % Length of Hough transform lines
            I = ones(size(B));
            [Hlen,~,~] = hough(I,'RhoResolution',app.rhoRes,'Theta',theta);
            % Image binarization
            T = adaptthresh(B, 0.2,'Statistic','mean'); % 0.2
            BW = imbinarize(B,T); %'adaptive');%,0.50);
            % Hough transform
            [H,T,R] = hough(BW,'RhoResolution',app.rhoRes,'Theta',theta);
            H2 = H./Hlen;
            H2(isnan(H2)) = 1;
            % Find peaks that are high in both transforms
            H = round(H.*H2);
            P = houghpeaks(H,app.numPeaksLimit,'Threshold',app.peakThreshold);
            % Find line segments of possible hyphae
            lines = houghlines(BW,T,R,P,'FillGap',app.fillGapLen,'MinLength',app.minLineLen);
            numLines = length(lines);
            peakRowColumn(1,:) = P(1,:);
            Pindex = 1;
            for loop = 2:numLines
                if (lines(loop).rho~=lines(loop-1).rho && lines(loop).theta~=lines(loop-1).theta)
                    Pindex = Pindex + 1;
                end
                peakRowColumn(loop,:) = P(Pindex,:);
            end
            
            % Compute feature criteria
            % Feature: Hough transform peak over mean
            peakOverAvg = zeros(numLines,1);
            H1 = H(:);
            meanH = mean(H1(H1~=0)); % Mean of non-zero array elements
            for loop = 1:numLines
                peakOverAvg(loop) = H(peakRowColumn(loop,1),peakRowColumn(loop,2))/meanH;
            end
            
            % Feature: Adjacent line segment intensity ratio
            % **** Modify to include mulitple adjacent lines different distances away *****
            adjacentLineRatio = zeros(numLines,1);
            if showGraphics
                xyForPlot = zeros(2,2,numLines);
                xyAdjForPlot = zeros(2,2,numLines,length(app.d));
            end
            for loop = 1:numLines
                xy = [lines(loop).point1;lines(loop).point2];
                lineProfile = improfile(BW,xy(:,1), xy(:,2));
                lineDensity = mean(lineProfile);
                % adjacent parallel line
                for loopd = 1:length(app.d)
                    if lines(loop).theta<0
                        adjPoint1 = [lines(loop).point1(1)-app.d(loopd)*cos(lines(loop).theta*pi/180),lines(loop).point1(2)-app.d(loopd)*sin(lines(loop).theta*pi/180)]; % [x,y]
                        adjPoint2 = [lines(loop).point2(1)-app.d(loopd)*cos(lines(loop).theta*pi/180),lines(loop).point2(2)-app.d(loopd)*sin(lines(loop).theta*pi/180)]; % [x,y]
                    else
                        adjPoint1 = [lines(loop).point1(1)+app.d(loopd)*cos(lines(loop).theta*pi/180),lines(loop).point1(2)+app.d(loopd)*sin(lines(loop).theta*pi/180)]; % [x,y]
                        adjPoint2 = [lines(loop).point2(1)+app.d(loopd)*cos(lines(loop).theta*pi/180),lines(loop).point2(2)+app.d(loopd)*sin(lines(loop).theta*pi/180)]; % [x,y]
                    end
                    xyAdj = [adjPoint1;adjPoint2];
                    if showGraphics
                        xyForPlot(:,:,loop) = xy;
                        xyAdjForPlot(:,:,loop,loopd) = xyAdj;
                    end
                    lineAdjProfile = improfile(BW,xyAdj(:,1), xyAdj(:,2));
                    lineAdjProfile(isnan(lineAdjProfile)) = []; % Remove NaN points caused by line extennding beyond image boundaries
                    if isempty(lineAdjProfile)
                        adjacentLineRatio(loop,loopd) = 1;
                    else
                        lineDensityAdj = mean(lineAdjProfile);
                        adjacentLineRatio(loop,loopd) = lineDensityAdj/lineDensity;
                        if isnan(adjacentLineRatio(loop,loopd))
                            disp('NaN')
                        end
                    end
                end
            end
            
            % Feature: Line segment density, density standard deviation,
            lineMean = zeros(1,numLines);
            lineVar= zeros(1,numLines);
            lineMoment3= zeros(1,numLines);
            lineMoment4= zeros(1,numLines);
            for loop = 1:numLines
                xy = [lines(loop).point1;lines(loop).point2];
                lineProfile = improfile(leafImage,xy(:,1), xy(:,2));
                lineMean(loop) = mean(lineProfile)/mean2(leafImage);
                profileStd = std(lineProfile,1); % sample std, not population estimate
                lineVar(loop) = moment(lineProfile,2)/mean(lineProfile)^2;
                lineMoment3(loop) = moment(lineProfile,3)/profileStd^3;
                lineMoment4(loop) = moment(lineProfile,4)/profileStd^4;
            end
            
            % Features: Line segment length, theta and rho
            lineLength = zeros(numLines,1);
            lineTheta = zeros(numLines,1);
            lineRho = zeros(numLines,1);
            for loop = 1:numLines
                lineLength(loop) = 0.001*(norm(lines(loop).point1 - lines(loop).point2)); % Divide by 1000 to normlize values
                lineTheta(loop) = lines(loop).theta;
                lineRho(loop) = lines(loop).rho;
            end
            
            % Apply feature criteria to find lines that are hyphae
            isHyphae = false(numLines,1);
            for loop = 1:numLines
                isHyphae(loop) = peakOverAvg(loop) > app.peakOverAvgThres &&...
                    adjacentLineRatio(loop,1) < app.adjacentLineRatioThres &&...
                    lineLength(loop) > 0.001*app.lineLengthThres;
            end
            hyphaeCnt = nnz(isHyphae);
            
            if showGraphics
                imshow(leafImage,'InitialMagnification','fit','Parent',app.StartingImgUIAxes);
                imshow(B*3,'InitialMagnification','fit','Parent',app.fibrousStruct); % x3
                imshow(BW,'InitialMagnification','fit','Parent',app.fig3)
                imshow(H,[],'XData',T,'YData',R,'InitialMagnification','fit','Parent',app.HoughTransform);
                xlabel(app.HoughTransform,'\theta'),ylabel(app.HoughTransform,'\rho');
                axis on;axis normal;
                title(app.HoughTransform,['Hough Transform, H (H^2/lenght)',', max(H) = ',num2str(max(H(:)),'%d')]);
                x = T(peakRowColumn(:,2));
                y = R(peakRowColumn(:,1));
                hold on
                plot(app.HoughTransform,x,y,'o','color','red');
                hold off
                imshow(BW,'InitialMagnification','fit','Parent',app.UIAxes2_2);
                hold on
                hyphCount = 0;
                for loop = 1:numLines
%                     for loopd = 1:1%length(d)
%                         hpAdj(loop,loopd) = plot(app.UIAxes2_2,xyAdjForPlot(:,1,loop,loopd), xyAdjForPlot(:,2,loop,loopd),'LineWidth',1,'Color','red');
%                     end
%                     drawnow;
%                     
%                     for loopd = 1:1 %length(d)
%                         set(hpAdj(loop,loopd),'visible','off');
%                     end
                    if isHyphae(loop)
                        hyphCount = hyphCount+1;
                        hp(hyphCount) = plot(app.UIAxes2_2,xyForPlot(:,1,loop), xyForPlot(:,2,loop),'LineWidth',2,'Color','green');
                        set(hp(hyphCount),'visible','off');
                    end
                end
                drawnow
            end
            for loop = 1:hyphCount
                set(hp(loop),'visible','on');
            end
            hold off
            drawnow
            
            app.featureMatrix = [peakOverAvg';adjacentLineRatio(:,1)';adjacentLineRatio(:,2)';adjacentLineRatio(:,3)';adjacentLineRatio(:,4)';...
                lineLength';lineMean;lineVar;lineMoment3;lineMoment4];
            app.targetMatrix = [userInput;~userInput];
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            WindowAPI(app.UIFigure,'Maximize');
        end

        % Button pushed function: HyphaeButton
        function HyphaeButtonPushed(app, event)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir();
            if path ~=0
                app.FolderEditField.Value = path;
                app.DropDown.Enable = 'on';
            end
        end

        % Button pushed function: NotHyphaeButton
        function NotHyphaeButtonPushed(app, event)
            
        end

        % Button pushed function: GoBack1Button
        function GoBack1ButtonPushed(app, event)
            
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            app.folder = app.FolderEditField.Value;
            if ~isfolder(app.folder)
                app.DropDown.Enable = 'off';
                app.HyphaeButton.Enable = 'off';
                app.NotHyphaeButton.Enable = 'off';
                app.GoBack1Button.Enable = 'off';
                uialert(app.UIFigure,'Folder not found','Invalid Folder',...
                    'Icon','warning');
            else
                app.fileList = dir(fullfile(app.folder));
                app.fileList = app.fileList(~[app.fileList.isdir]);
                [fold,name,ext] = arrayfun(@(x) fileparts(fullfile(x.folder,x.name)),app.fileList,'UniformOutput',false);
                ext = unique(ext);
                app.DropDown.Items = [app.DropDown.Items, ext'];
                app.DropDown.Enable = 'on';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            selection = uiconfirm(app.UIFigure,'Use this extention?','Confirm Extention',...
                'Icon','warning');
            if strcmpi(selection, 'OK')
                app.HyphaeButton.Enable = 'on';
                app.NotHyphaeButton.Enable = 'on';
                app.GoBack1Button.Enable = 'on';
                app.fileList = dir(fullfile(app.folder,['*.',value]));
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartingImgUIAxes = uiaxes(app.UIFigure);
            app.HyphaeButton = uibutton(app.UIFigure, 'push');
            app.HyphaeButton.ButtonPushedFcn = createCallbackFcn(app, @HyphaeButtonPushed, true);
            app.HyphaeButton.Enable = 'off';
            app.HyphaeButton.Text = 'Hyphae';
            app.NotHyphaeButton = uibutton(app.UIFigure, 'push');
            app.NotHyphaeButton.ButtonPushedFcn = createCallbackFcn(app, @NotHyphaeButtonPushed, true);
            app.NotHyphaeButton.Enable = 'off';
            app.NotHyphaeButton.Text = 'Not Hyphae';
            app.FolderEditFieldLabel = uilabel(app.UIFigure);
            app.FolderEditFieldLabel.Text = 'Folder:';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.GoBack1Button = uibutton(app.UIFigure, 'push');
            app.GoBack1Button.ButtonPushedFcn = createCallbackFcn(app, @GoBack1ButtonPushed, true);
            app.GoBack1Button.Enable = 'off';
            app.GoBack1Button.Text = 'Go Back 1';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Choose Ext'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Choose Ext';
            app.fibrousStruct = uiaxes(app.UIFigure);
            app.HoughTransform = uiaxes(app.UIFigure);
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            app.fig3 = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = HyphaeSelection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end